classdef omergui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NOTESTextArea                 matlab.ui.control.TextArea
        RUNPSOButton                  matlab.ui.control.Button
        PaperBestSolutionEditField    matlab.ui.control.EditField
        PaperBestSolutionEditFieldLabel  matlab.ui.control.Label
        StabilityResultsPanel         matlab.ui.container.Panel
        InnerLoopPMEditField          matlab.ui.control.NumericEditField
        InnerLoopPMEditFieldLabel     matlab.ui.control.Label
        InnerLoopGMdBEditField        matlab.ui.control.NumericEditField
        InnerLoopGMdBEditFieldLabel   matlab.ui.control.Label
        InterLoopPMEditField          matlab.ui.control.NumericEditField
        InterLoopPMEditFieldLabel     matlab.ui.control.Label
        InterLoopGMdBEditField        matlab.ui.control.NumericEditField
        InterLoopGMdBEditFieldLabel   matlab.ui.control.Label
        OuterLoopPMEditField          matlab.ui.control.NumericEditField
        OuterLoopPMEditFieldLabel     matlab.ui.control.Label
        OuterLoopGMdBEditField        matlab.ui.control.NumericEditField
        OuterLoopGMdBEditFieldLabel   matlab.ui.control.Label
        PSOResultsPanel               matlab.ui.container.Panel
        BestCostEditField             matlab.ui.control.NumericEditField
        BestCostEditFieldLabel        matlab.ui.control.Label
        BestSolnkaEditField           matlab.ui.control.NumericEditField
        BestSolnkaEditFieldLabel      matlab.ui.control.Label
        BestSolnkiEditField           matlab.ui.control.NumericEditField
        BestSolnkiEditFieldLabel      matlab.ui.control.Label
        PerformanceResultsPanel       matlab.ui.container.Panel
        SettTimesecEditField_2        matlab.ui.control.NumericEditField
        SettTimesecLabel_2            matlab.ui.control.Label
        OvershootpercentEditField_2   matlab.ui.control.NumericEditField
        OvershootpercentEditField_2Label  matlab.ui.control.Label
        RiseTimesecEditField_2        matlab.ui.control.NumericEditField
        RiseTimesecEditField_2Label   matlab.ui.control.Label
        UndershootpercentEditField_2  matlab.ui.control.NumericEditField
        UndershootpercentEditField_2Label  matlab.ui.control.Label
        PSOParametersPanel            matlab.ui.container.Panel
        NumbSwarmEditField            matlab.ui.control.NumericEditField
        NumbSwarmEditFieldLabel       matlab.ui.control.Label
        MaxIterEditField              matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel         matlab.ui.control.Label
        PerformanceRequirementsPanel  matlab.ui.container.Panel
        SettTimesecEditField          matlab.ui.control.NumericEditField
        SettTimesecLabel              matlab.ui.control.Label
        RiseTimesecEditField          matlab.ui.control.NumericEditField
        RiseTimesecEditFieldLabel     matlab.ui.control.Label
        OvershootpercentEditField     matlab.ui.control.NumericEditField
        OvershootLabel                matlab.ui.control.Label
        UndershootpercentEditField    matlab.ui.control.NumericEditField
        UndershootLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: PerformanceRequirementsPanel, 
        % ...and 1 other component
        function PerformanceRequirementsPanelSizeChanged(app, event)
            position = app.PerformanceRequirementsPanel.Position;

        end

        % Button pushed function: RUNPSOButton
        function RUNPSOButtonPushed(app, event)
            warning off;
            % rad = pi/180;

            cla(app.UIAxes);

            kr = 0.2903 ; % chosen by looking bandwidth ratio(1/3)
            A =  10^(5/20);   %1.9953; %  6-dB gain margin(required, design parameter)
            theta = 30*pi/180; % phase margin must be radian


            % 30 deg & 5 dB works fine

            %% Vehicle Dynamics & TFs

            s = tf('s');
            wd = 22.4;
            ksi_d = 0.052;
            K1 = -1116.5;
            K3 = 0.6477;
            T_alpha = 0.676;
            A11 = 0.001054;
            A12 = -0.00081;

            G_q = K3*(1+T_alpha*s)*wd^2/(s^2+2*ksi_d*wd*s+wd^2);

            G_z = K1*(1+A11*s+A12*s^2)*wd^2/(s^2+2*ksi_d*wd*s + wd^2);

            %% Actuator & Rate Gyro TFs

            ksi_a = 0.7;
            wa = 250;
            ksi_r = 0.65;
            wr = 500;

            G_acc = wa^2/(s^2+2*ksi_a*wa*s+wa^2);

            G_gyro = wr^2/(s^2+2*ksi_r*wr*s+wr^2);


            %% FIRST PHASE => STABILITY

            logg = linspace(1,100,200);


            xsoln1 =[]; % inner
            xsoln2 =[]; % inter
            xsoln3 =[]; % outer

            for i=1:length(logg)

                w = logg(i);

                % Modified Version to Handle Large Number effects on numerical solution
                Ni = 1;
                Di = j*w;
                Nacc = wa^2;
                Dacc = ((j*w)^2+2*ksi_a*wa*j*w+wa^2);
                Ngyro = wr^2;
                Dgyro = ((j*w)^2+2*ksi_r*wr*j*w+wr^2);
                Nq = K3*(1+T_alpha*j*w);
                Dq = ((j*w)^2+2*ksi_d*wd*j*w+wd^2)/wd^2;
                Nz = K1*(1+A11*(j*w)+A12*(j*w)^2);
                Dz = ((j*w)^2+2*ksi_d*wd*(j*w)+wd^2)/wd^2;


                % for outer loop

                P0 = kr*Nacc*Nq*Ngyro*Ni*Dz;
                Q0 = A*exp(-j*theta)*kr*Ni*Nacc*Nz*Dq*Dgyro;
                R0 = Di*Dacc*Dz*Dq*Dgyro + kr*Nacc*Ngyro*Nq*Di*Dz;

                P_real = double(real(P0)); % to solve with fsolve function
                P_imag = double(imag(P0));
                Q_real = double(real(Q0));
                Q_imag = double(imag(Q0));
                R_real = double(real(R0));
                R_imag = double(imag(R0));

                x0 = [1;-1]; %we know ka will be negative


                F = @(x) [P_real*x(1)+Q_real*x(1)*x(2)+R_real;
                    P_imag*x(1)+Q_imag*x(1)*x(2)+R_imag];


                options = optimoptions('fsolve','MaxFunEvals',500,'Tolfun',1e-3);

                [x,fval,exitflag] = fsolve(F,x0,options);

                if exitflag > 0
                    xsoln3 = [xsoln3 x];
                end

                clear x;

                % intermediate loop

                P0 = A*exp(-j*theta)*kr*Nacc*Nq*Ngyro*Ni*Dz;
                Q0 = A*exp(-j*theta)*kr*Nacc*Ni*Nz*Dq*Dgyro;
                R0 = Dacc*Di*Dq*Dgyro*Dz + kr*Nacc*Ngyro*Nq*Di*Dz;


                P_real = double(real(P0));
                P_imag = double(imag(P0));
                Q_real = double(real(Q0));
                Q_imag = double(imag(Q0));
                R_real = double(real(R0));
                R_imag = double(imag(R0));

                x0 = [1;-1]; %we know ka will be negative


                F = @(x) [P_real*x(1)+Q_real*x(1)*x(2)+R_real;
                    P_imag*x(1)+Q_imag*x(1)*x(2)+R_imag];


                options = optimoptions('fsolve','MaxFunEvals',500,'Tolfun',1e-3);

                [x,fval,exitflag] = fsolve(F,x0,options);

                if exitflag > 0
                    xsoln2 = [xsoln2 x];
                end

                clear x;

                %     inner loop

                P0 = A*exp(-j*theta)*kr*Nacc*Nq*Ngyro*Ni*Dz;
                Q0 = A*exp(-j*theta)*kr*Ni*Nacc*Nz*Dq*Dgyro;
                R0 = Dacc*Di*Dq*Dgyro*Dz + A*exp(-j*theta)*kr*Nacc*Ngyro*Nq*Di*Dz;

                P_real = double(real(P0));
                P_imag = double(imag(P0));
                Q_real = double(real(Q0));
                Q_imag = double(imag(Q0));
                R_real = double(real(R0));
                R_imag = double(imag(R0));

                x0 = [1;-1]; %we know ka will be negative


                F = @(x) [P_real*x(1)+Q_real*x(1)*x(2)+R_real;
                    P_imag*x(1)+Q_imag*x(1)*x(2)+R_imag];

                options = optimoptions('fsolve','MaxFunEvals',500,'Tolfun',1e-3);


                [x,fval,exitflag] = fsolve(F,x0,options);


                if exitflag > 0
                    xsoln1 = [xsoln1 x];

                end


                clear x;

            end

            xq1 = [-0.05:0.001:0];
            vq1 = interp1(xsoln1(2,1:(end-18)),xsoln1(1,1:(end-18)),xq1,'linear','extrap');

            xq2 = [-0.05:0.001:0];
            vq2 = interp1(xsoln2(2,:),xsoln2(1,:),xq2,'spline','extrap');

            xq3 = [-0.05:0.001:0];
            vq3 = interp1(xsoln3(2,:),xsoln3(1,:),xq3,'linear','extrap');


            %% SECOND PHASE => PSO SEARCH REGION

            k_al = -0.02;
            k_au = 0;
            k_il = 0;

            for i = 1:length(xq1)

                k_iu(i) = min([vq1(i) vq2(i) vq3(i)]);

            end
            ka_vec = [-0.02:0.0001:0];

            p = polyfit(xq1,k_iu,9);
            y = polyval(p,ka_vec);

            p_fun = @(x) p(1)*x^9 + p(2)*x^8 + p(3)*x^7 + p(4)*x^6 + p(5)*x^5 ...
                + p(6)*x^4 + p(7)*x^3 + p(8)*x^2 + p(9)*x + p(10);


            %% THIRD PHASE => PERFORMANCE OPTIMIZATION with PSO

            % use the notation of xsoln = [ki ka];

            % Performance Criterion

            weights.w1 = 1; % weight for undershoot error
            weights.w2 = 1; % weight for overshoot error
            weights.w3 = 10; % weight for settling time error
            weights.w4 = 10; % weight for risetime error

            % Desired Perfomance Respectivley
            des_perfomance.us = app.UndershootpercentEditField.Value;
            des_perfomance.os = app.OvershootpercentEditField.Value;
            des_perfomance.ts = app.SettTimesecEditField.Value;
            des_perfomance.rs = app.RiseTimesecEditField.Value;

            % Problem Definition

            problem.CostFunction = @(x,des_performance,weights) performance_td(x,des_performance,weights);% cost function definition

            problem.nVar = 2; % number of variables

            problem.VarMinf =  [0 -0.02] ;  % lower bound for variables

            problem.VarMaxf = @(x) [p_fun x];  % upper bound for variables

            % PSO Paramaters

            param.MaxIt = app.MaxIterEditField.Value;
            param.nPop = app.NumbSwarmEditField.Value;
            param.w = 1;
            param.wdamp = 0.99;
            param.c1 = 2;
            param.c2 = 2;

            % Call PSO % Results

            disp('#############');
            disp('PSO START');

            out = PSO(problem,param,des_perfomance,weights);

            disp('PSO END');
            disp('#############');

            xcost = out.BestCost;
            xsoln = double(out.BestSoln.Position);

            app.BestSolnkiEditField.Value = xsoln(1);
            app.BestSolnkaEditField.Value = xsoln(2);
            app.BestCostEditField.Value = xcost;

            seq = (param.MaxIt/5);
            cost1_seq = out.BestCost_Array(2:seq);
            cost2_seq = out.BestCost_Array(seq+1:2*seq);
            cost3_seq = out.BestCost_Array(2*seq+1:3*seq);
            cost4_seq = out.BestCost_Array(3*seq+1:4*seq);
            cost5_seq = out.BestCost_Array(4*seq+1:5*seq);

            plot(app.UIAxes,out.BestCost_Array(2:end),'LineWidth',2); % since first element is too high(assigned by me)
            hold (app.UIAxes,'on');
            scatter(app.UIAxes,seq,cost2_seq(1),100,'red','filled');
             hold (app.UIAxes,'on');
            scatter(app.UIAxes,2*seq,cost3_seq(1),100,'green','filled');
             hold (app.UIAxes,'on');
            scatter(app.UIAxes,3*seq,cost4_seq(1),100,'blue','filled');
             hold (app.UIAxes,'on');
            scatter(app.UIAxes,4*seq,cost5_seq(1),100,'black','filled');
            title(app.UIAxes,'PSO Cost Calculation');
            xlabel(app.UIAxes,'Iteration Number');
            ylabel(app.UIAxes,'Cost Value');
            grid(app.UIAxes,"on");
            leg = legend(app.UIAxes,'Cost over Time','PSO 1st RESET','PSO 2nd RESET','PSO 3rd RESET','PSO 4th RESET');
            fontsize(leg,scale=0.8);

            %% Performance Analysis

            kr = 0.2903 ; % chosen by looking bandwidth ratio(1/3)

            s = tf('s');
            wd = 22.4;
            ksi_d = 0.052;
            K1 = -1116.5;
            K3 = 0.6477;
            T_alpha = 0.676;
            A11 = 0.001054;
            A12 = -0.00081;

            G_q = K3*(1+T_alpha*s)*wd^2/(s^2+2*ksi_d*wd*s+wd^2);

            G_z = K1*(1+A11*s+A12*s^2)*wd^2/(s^2+2*ksi_d*wd*s + wd^2);

            ksi_a = 0.7;
            wa = 250;
            ksi_r = 0.65;
            wr = 500;

            G_acc = wa^2/(s^2+2*ksi_a*wa*s+wa^2);

            G_gyro = wr^2/(s^2+2*ksi_r*wr*s+wr^2);

            G_ol = (xsoln(2)*xsoln(1)*(1/s)*kr*G_acc*G_z)/(1 + kr*G_acc*G_gyro*G_q + kr*G_acc*G_q*G_gyro*xsoln(1)*(1/s));

            G_cl = minreal(G_ol/(1+G_ol));

            y = stepinfo(G_cl); % we can obtain required time domain data from here

            app.UndershootpercentEditField_2.Value = y.Undershoot;
            app.OvershootpercentEditField_2.Value = y.Overshoot;
            app.RiseTimesecEditField_2.Value = y.RiseTime;
            app.SettTimesecEditField_2.Value = y.SettlingTime;

            %% Stability Analysis

            G_ol_outer = (xsoln(2)*xsoln(1)*(1/s)*kr*G_acc*G_z)/(1 + kr*G_acc*G_gyro*G_q + kr*G_acc*G_q*G_gyro*xsoln(1)*(1/s));

            G_ol_inter = (xsoln(2)*xsoln(1)*(1/s)*kr*G_acc*G_z + kr*G_acc*G_q*G_gyro*xsoln(1)*(1/s))/(1+kr*G_acc*G_gyro*G_q);

            G_ol_inner = xsoln(2)*xsoln(1)*(1/s)*kr*G_acc*G_z + kr*G_acc*G_q*G_gyro*xsoln(1)*(1/s) + kr*G_acc*G_gyro*G_q;

            % We can see that all 3 loops are satisfying the "stability requirements".

           
            [GM_out PM_out] = margin(G_ol_outer);
            GM_out = 20*log10(GM_out);

            app.OuterLoopGMdBEditField.Value = GM_out;
            app.OuterLoopPMEditField.Value = PM_out;
            
            [GM_int PM_int] = margin(G_ol_inter);
            GM_int = 20*log10(GM_int);

            app.InterLoopGMdBEditField.Value = GM_int;
            app.InterLoopPMEditField.Value = PM_int;
            
            [GM_inn PM_inn] = margin(G_ol_inner);
            GM_inn = 20*log10(GM_inn);

            app.InnerLoopGMdBEditField.Value = GM_inn;
            app.InnerLoopPMEditField.Value = PM_inn;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PerformanceRequirementsPanel = uipanel(app.UIFigure);
            app.PerformanceRequirementsPanel.Title = 'Performance Requirements';
            app.PerformanceRequirementsPanel.SizeChangedFcn = createCallbackFcn(app, @PerformanceRequirementsPanelSizeChanged, true);
            app.PerformanceRequirementsPanel.ButtonDownFcn = createCallbackFcn(app, @PerformanceRequirementsPanelSizeChanged, true);
            app.UndershootLabel = uilabel(app.PerformanceRequirementsPanel);
            app.UndershootLabel.Text = 'Undershoot(percent)';
            app.UndershootpercentEditField = uieditfield(app.PerformanceRequirementsPanel, 'numeric');
            app.UndershootpercentEditField.Value = 10;
            app.OvershootLabel = uilabel(app.PerformanceRequirementsPanel);
            app.OvershootLabel.Text = 'Overshoot(percent)';
            app.OvershootpercentEditField = uieditfield(app.PerformanceRequirementsPanel, 'numeric');
            app.OvershootpercentEditField.Value = 2;
            app.RiseTimesecEditFieldLabel = uilabel(app.PerformanceRequirementsPanel);
            app.RiseTimesecEditFieldLabel.Text = 'RiseTime(sec)';
            app.RiseTimesecEditField = uieditfield(app.PerformanceRequirementsPanel, 'numeric');
            app.RiseTimesecEditField.Value = 0.15;
            app.SettTimesecLabel = uilabel(app.PerformanceRequirementsPanel);
            app.SettTimesecLabel.Text = 'SettTime(sec)';
            app.SettTimesecEditField = uieditfield(app.PerformanceRequirementsPanel, 'numeric');
            app.SettTimesecEditField.Value = 0.3;
            app.PSOParametersPanel = uipanel(app.UIFigure);
            app.PSOParametersPanel.Title = 'PSO Parameters';
            app.MaxIterEditFieldLabel = uilabel(app.PSOParametersPanel);
            app.MaxIterEditFieldLabel.Text = 'Max Iter';
            app.MaxIterEditField = uieditfield(app.PSOParametersPanel, 'numeric');
            app.MaxIterEditField.Value = 100;
            app.NumbSwarmEditFieldLabel = uilabel(app.PSOParametersPanel);
            app.NumbSwarmEditFieldLabel.Text = 'Numb Swarm';
            app.NumbSwarmEditField = uieditfield(app.PSOParametersPanel, 'numeric');
            app.NumbSwarmEditField.Value = 10;
            app.PerformanceResultsPanel = uipanel(app.UIFigure);
            app.PerformanceResultsPanel.Title = 'Performance Results ';
            app.UndershootpercentEditField_2Label = uilabel(app.PerformanceResultsPanel);
            app.UndershootpercentEditField_2Label.Text = 'Undershoot(percent)';
            app.UndershootpercentEditField_2 = uieditfield(app.PerformanceResultsPanel, 'numeric');
            app.RiseTimesecEditField_2Label = uilabel(app.PerformanceResultsPanel);
            app.RiseTimesecEditField_2Label.Text = 'RiseTime(sec)';
            app.RiseTimesecEditField_2 = uieditfield(app.PerformanceResultsPanel, 'numeric');
            app.OvershootpercentEditField_2Label = uilabel(app.PerformanceResultsPanel);
            app.OvershootpercentEditField_2Label.Text = 'Overshoot(percent)';
            app.OvershootpercentEditField_2 = uieditfield(app.PerformanceResultsPanel, 'numeric');
            app.SettTimesecLabel_2 = uilabel(app.PerformanceResultsPanel);
            app.SettTimesecLabel_2.Text = 'SettTime(sec)';
            app.SettTimesecEditField_2 = uieditfield(app.PerformanceResultsPanel, 'numeric');
            app.PSOResultsPanel = uipanel(app.UIFigure);
            app.PSOResultsPanel.Title = 'PSO Results';
            app.BestSolnkiEditFieldLabel = uilabel(app.PSOResultsPanel);
            app.BestSolnkiEditFieldLabel.Text = 'Best Soln (ki)';
            app.BestSolnkiEditField = uieditfield(app.PSOResultsPanel, 'numeric');
            app.BestSolnkaEditFieldLabel = uilabel(app.PSOResultsPanel);
            app.BestSolnkaEditFieldLabel.Text = 'Best Soln (ka)';
            app.BestSolnkaEditField = uieditfield(app.PSOResultsPanel, 'numeric');
            app.BestCostEditFieldLabel = uilabel(app.PSOResultsPanel);
            app.BestCostEditFieldLabel.Text = 'Best Cost';
            app.BestCostEditField = uieditfield(app.PSOResultsPanel, 'numeric');
            app.StabilityResultsPanel = uipanel(app.UIFigure);
            app.StabilityResultsPanel.Title = 'Stability Results';
            app.OuterLoopGMdBEditFieldLabel = uilabel(app.StabilityResultsPanel);
            app.OuterLoopGMdBEditFieldLabel.Text = 'OuterLoop GM(dB)';
            app.OuterLoopGMdBEditField = uieditfield(app.StabilityResultsPanel, 'numeric');
            app.OuterLoopPMEditFieldLabel = uilabel(app.StabilityResultsPanel);
            app.OuterLoopPMEditFieldLabel.Text = 'OuterLoop PM';
            app.OuterLoopPMEditField = uieditfield(app.StabilityResultsPanel, 'numeric');
            app.InterLoopGMdBEditFieldLabel = uilabel(app.StabilityResultsPanel);
            app.InterLoopGMdBEditFieldLabel.Text = 'InterLoop GM(dB)';
            app.InterLoopGMdBEditField = uieditfield(app.StabilityResultsPanel, 'numeric');
            app.InterLoopPMEditFieldLabel = uilabel(app.StabilityResultsPanel);
            app.InterLoopPMEditFieldLabel.Text = 'InterLoop PM';
            app.InterLoopPMEditField = uieditfield(app.StabilityResultsPanel, 'numeric');
            app.InnerLoopGMdBEditFieldLabel = uilabel(app.StabilityResultsPanel);
            app.InnerLoopGMdBEditFieldLabel.Text = 'InnerLoop GM(dB)';
            app.InnerLoopGMdBEditField = uieditfield(app.StabilityResultsPanel, 'numeric');
            app.InnerLoopPMEditFieldLabel = uilabel(app.StabilityResultsPanel);
            app.InnerLoopPMEditFieldLabel.Text = 'InnerLoop PM';
            app.InnerLoopPMEditField = uieditfield(app.StabilityResultsPanel, 'numeric');
            app.PaperBestSolutionEditFieldLabel = uilabel(app.UIFigure);
            app.PaperBestSolutionEditFieldLabel.Text = 'Paper Best Solution';
            app.PaperBestSolutionEditField = uieditfield(app.UIFigure, 'text');
            app.PaperBestSolutionEditField.Value = '       (ki = 8.6182, ka = -0.006)';
            app.RUNPSOButton = uibutton(app.UIFigure, 'push');
            app.RUNPSOButton.ButtonPushedFcn = createCallbackFcn(app, @RUNPSOButtonPushed, true);
            app.RUNPSOButton.Text = 'RUN PSO ';
            app.NOTESTextArea = uitextarea(app.UIFigure);
            app.NOTESTextArea.Value = {'NOTE : Please enter "Max-Iter" as multiplicative of 5. PSO algorithm reset itselves 4 times in case of local minima convergence problem.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = omergui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end